classdef ecg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AlertLamp                     matlab.ui.control.Lamp
        AlertLampLabel                matlab.ui.control.Label
        PaitentMonitoringSystemLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        HeartBeatEditField            matlab.ui.control.NumericEditField
        HeartBeatEditFieldLabel       matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 
            Fs = 360; % Sampling Frequency

            %% Visualization of ECG Signal
            load ('100m.mat');
            ecgsig = val/200;
            t = 0:length(ecgsig)-1;
            tx = t./Fs;

            plot(app.UIAxes,tx,ecgsig)
            
            %% Removal of Baseline wander using Biorthogonal Wavelet
            [C, L] = wavedec (ecgsig,9,'bior3.7'); % Decomposition
            a9 = wrcoef ('a', C, L,'bior3.7',9); % Approximate Component
            d9 = wrcoef ('d', C, L,'bior3.7',9); % Detailed components
            d8 = wrcoef ('d', C, L,'bior3.7',8);
            d7 = wrcoef ('d', C, L,'bior3.7',7);
            d6 = wrcoef ('d', C, L,'bior3.7',6);
            d5 = wrcoef ('d', C, L,'bior3.7',5);
            d4 = wrcoef ('d', C, L,'bior3.7',4);
            d3 = wrcoef ('d', C, L,'bior3.7',3);
            d2 = wrcoef ('d', C, L,'bior3.7',2);
            d1 = wrcoef ('d', C, L,'bior3.7',1);
            y0= d9+d8+d7+d6+d5+d4+d3+d2+d1;
            

            %% Removal of Powerline Interference using Notch Filter
            Fnotch = 50; % Notch Frequency
            BW = 100; % Bandwidth
            Apass = 1; % Bandwidth Attenuation
            [b, a] = iirnotch (Fnotch/ (Fs/2), BW/ (Fs/2), Apass);
            Hd1 = dfilt.df2 (b, a);
            y1=filter (Hd1, y0);
            

            %% Removal of EMG noise using Biorthogonal wavelet
            [C, L] = wavedec (y1,2,'bior3.7'); % Decomposition
            a2 = wrcoef ('a', C, L,'bior3.7',2); % Approximate Component
            d2 = wrcoef ('d', C, L,'bior3.7',2); % Detailed components
            d1 = wrcoef ('d', C, L,'bior3.7',1);
            y2 = a2 + d2;
            
            plot(app.UIAxes2,tx,y2)

            %% Denoising of ECG Signal using Daubechies wavelet
            [C, L] = wavedec (y2,4,'db4'); % Decomposition
            a4 = wrcoef ('a', C, L,'db4',4); % Approximate Component
            d4 = wrcoef ('d', C, L,'db4',4); % Detailed components
            d3 = wrcoef ('d', C, L,'db4',3);
            d2 = wrcoef ('d', C, L,'db4',2);
            d1 = wrcoef ('d', C, L,'db4',1);
            y3 = a4 + d4 + d3;

            %% PQRST Detection
            [Rpeaks,locs_r] = findpeaks(y3,t,'MinPeakHeight',0.4,'MinPeakDist',50);
            nohb_r = length(locs_r);
            for i = 1:nohb_r
                Speaks(i) = min(y3(locs_r(i):locs_r(i)+15));
                locs_s(i) = find(y3==Speaks(i))-1;
            end

            for i = 1:nohb_r
                Qpeaks(i) = min(y3(locs_r(i)-15:locs_r(i)));
                locs_q(i) = find(y3==Qpeaks(i));
            end

            for i = 1:nohb_r
                if locs_q(i) - 60 > 0
                    Ppeaks(i) = max(y3(locs_q(i)-60:locs_q(i)));
                    locs_p(i) = find(y3==Ppeaks(i));
                else
                    Ppeaks(i) = max(y3(locs_q(i)-10:locs_q(i)));
                    locs_p(i) = find(y3==Ppeaks(i));
                end
            end

            for i = 1:nohb_r
                if locs_s(i) + 130 <= 3600
                    Tpeaks(i) = max(y3(locs_s(i):locs_s(i)+130));
                    locs_t(i) = find(y3==Tpeaks(i));
                else
                    break
                end
            end


            timelimit = length(ecgsig)/Fs;
            hbpermin = (nohb_r*60)/timelimit;
            
            hold(app.UIAxes3);
            plot(app.UIAxes3,t,y3)
            
            xlim(app.UIAxes3,[0,length(ecgsig)])
            

            plot(app.UIAxes3,locs_r,Rpeaks,'^r');
            plot(app.UIAxes3,locs_s,Speaks,'xm')
            plot(app.UIAxes3,locs_q,Qpeaks,'*g')
            plot(app.UIAxes3,locs_p,Ppeaks,'om')
            plot(app.UIAxes3,locs_t,Tpeaks,'pk')
            
            if  hbpermin > 80 
                app.AlertLamp.Colour= 'r'
            else 
                app.AlertLamp.Color='g'
            end
            
            app.HeartBeatEditField.Value = hbpermin 


            %Hearbeat sesh
            
            
            delete(instrfind({'Port'},{'COM3'}));

            
            s = serial('COM3');
            fopen(s);

            global time
            time = zeros(1, 50);


            hPlot = plot(app.UIAxes4,time);
            
            while 1
                
                val = str2double(fscanf(s));

                if ~isempty(val) 
                    time(51) = val;
                    time(1) = [];
                    time = movmean(time, 3);
                    ymax = max(time) + 2;
                    ymin = min(time) - 1;

                    
                    set(hPlot, 'YData', time);
                    ylim(app.UIAxes4, [ymin, ymax]);
                    drawnow; 
                    pause(0.05);
                                   
                else
                    disp('Error');
                end
            end    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG Signal with artifacts')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Noise Removed')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Peaks Detected')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Body Temp')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'F')
            zlabel(app.UIAxes4, 'Z')
            app.HeartBeatEditFieldLabel = uilabel(app.UIFigure);
            app.HeartBeatEditFieldLabel.Text = 'Heart Beat';
            app.HeartBeatEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PaitentMonitoringSystemLabel = uilabel(app.UIFigure);
            app.PaitentMonitoringSystemLabel.Text = 'Paitent Monitoring System';
            app.AlertLampLabel = uilabel(app.UIFigure);
            app.AlertLampLabel.Text = 'Alert';
            app.AlertLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end